classdef GIFcarpeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HacerGIFUIFigure        matlab.ui.Figure
        CarpetaEntradaLabel     matlab.ui.control.Label
        edtCarpeta1             matlab.ui.control.EditField
        NombreGIFdesalidaLabel  matlab.ui.control.Label
        edtNombre               matlab.ui.control.EditField
        btnHacerGif             matlab.ui.control.Button
        LogLabel                matlab.ui.control.Label
        edtLog                  matlab.ui.control.EditField
        CarpetaSalidaLabel      matlab.ui.control.Label
        edtCarpeta2             matlab.ui.control.EditField
        btnAbrir1               matlab.ui.control.Button
        btnAbrir2               matlab.ui.control.Button
        OpcionesPanel           matlab.ui.container.Panel
        ckbGirar                matlab.ui.control.CheckBox
        ckbRepetir              matlab.ui.control.CheckBox
        OrdenDropDownLabel      matlab.ui.control.Label
        ddOrden                 matlab.ui.control.DropDown
        ParmetrosPanel          matlab.ui.container.Panel
        DesdeEditFieldLabel     matlab.ui.control.Label
        edtDesde                matlab.ui.control.NumericEditField
        HastaEditFieldLabel     matlab.ui.control.Label
        edtHasta                matlab.ui.control.NumericEditField
        dtsLabel                matlab.ui.control.Label
        edtDt                   matlab.ui.control.NumericEditField
        EscalaEditFieldLabel    matlab.ui.control.Label
        edtEscala               matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function path_out = obtenerCarpeta(app, path_ir)
            if not(path_ir(end) == '\' || path_ir(end) == '/')
                path_ir = [path_ir,'\'];
            end
            path_out = path_ir;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            global cerrar
            cerrar = false;
            if nargin > 1
                app.edtCarpeta1.Value = varargin{1};
                app.edtCarpeta2.Value = varargin{2};
                app.edtNombre.Value = varargin{3};
                app.edtEscala.Value = varargin{4};
                app.edtDt.Value = varargin{5};
                btnHacerGifButtonPushed(app);
                cerrar = true;
            end
        end

        % Button pushed function: btnHacerGif
        function btnHacerGifButtonPushed(app, event)
            global cerrar
        
            % Cargar Carpetas y nombre:
            carpeta1 = obtenerCarpeta(app, app.edtCarpeta1.Value);
            carpeta2 = obtenerCarpeta(app, app.edtCarpeta2.Value);
            nombre = app.edtNombre.Value;
            escala = app.edtEscala.Value;
            dt = app.edtDt.Value;
            
            % Leer info de archivos en carpeta1 de entrada:
            Files = dir([carpeta1,'*g']);
            Nfotos = length(Files);
            
            % Definir inicio de imágenes:
            if app.edtDesde.Value == 0
                ini = 1;
            else
                ini = app.edtDesde.Value;
            end
            
            % Definir fin de imágenes:
            if app.edtHasta.Value == 0
                fin = Nfotos;
            else
                fin = min(Nfotos , app.edtHasta.Value);
            end
            
            % Variable de salida:
            
            % Si se repite:
            if app.ckbRepetir.Value
                imagenes = cell(1 , 2*(fin-ini)-2);
                if app.ddOrden.Value == "Ascendente"
                    imgs = [ini:fin , fin-1:-1:ini+1];
                else
                    imgs = [fin:-1:ini , ini+1:fin-1];
                end
            else
                imagenes = cell(1 , fin-ini);
                if app.ddOrden.Value == "Ascendente"
                    imgs = ini:fin;
                else
                    imgs = fin:-1:ini;
                end
            end
            
            
            % Barra de progreso:
            wb = waitbar(0,'Recompilando Imágenes ...');
            wbi = 0; dwb=1/length(imgs);
            waitbar(wbi);
%             WinOnTop(wb);
            
            % Recopilar imágenes:
            for f = 1:length(imgs)
                i = imgs(f);
                % Leer Nombre de Archivo y número de foto
                FileName = Files(i).name;
                
                % Leer imagen:
                img = imread([carpeta1,FileName]);
                img = im2double(img);
                if length(size(img))==2
                    img = cat(3,img,img,img);
                end
                app.edtLog.Value = FileName;
                
                % Escalar Imagen:
                if escala < 1
                    img = imgaussfilt(img, 0.8/escala);
                    img = imresize(img, escala); 
                end
                
                % Girar 180º si necesario
                if app.ckbGirar.Value
                    img = imrotate(img,180);
                end
                
                imagenes{f} = img; %uint8(255*img);
                
                wbi = wbi + dwb;
                waitbar(wbi);
            end
            
            app.edtLog.Value = 'Generando GIF...';
            waitbar(1, wb, 'Generando GIF...');
            
            
            % Hacer GIF
            arch = [carpeta2, nombre];
            HacerGIF(imagenes, arch, dt);
            
            close(wb);
            app.edtLog.Value = 'Listo!! XD';
            
            if cerrar
                close(app.HacerGIFUIFigure)
            end
        end

        % Button pushed function: btnAbrir1
        function btnAbrir1ButtonPushed(app, event)
            carpeta1 = app.edtCarpeta1.Value;
            
            if ~exist(carpeta1,'dir')
                carpeta1 = matlabroot;
            end
            
            path_in = uigetdir(carpeta1,'Escoja la carpeta de entrada');
            app.edtCarpeta1.Value = path_in;
%             app.HacerGIFUIFigure.Visible = 'off';
%             app.HacerGIFUIFigure.Visible = 'on';
        end

        % Button pushed function: btnAbrir2
        function btnAbrir2ButtonPushed(app, event)
            carpeta2 = app.edtCarpeta2.Value; 
            
            if ~exist(carpeta2,'dir')
                carpeta2 = matlabroot;
            end
            
            path_out = uigetdir(carpeta2,'Escoja la carpeta de salida');
            app.edtCarpeta2.Value = path_out;
%             app.HacerGIFUIFigure.Visible = 'off';
%             app.HacerGIFUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HacerGIFUIFigure = uifigure('Visible', 'off');
            app.HacerGIFUIFigure.Name = 'Hacer GIF';
            app.CarpetaEntradaLabel = uilabel(app.HacerGIFUIFigure);
            app.CarpetaEntradaLabel.Text = 'Carpeta Entrada:';
            app.edtCarpeta1 = uieditfield(app.HacerGIFUIFigure, 'text');
            app.edtCarpeta1.Value = 'C:\Users\Danki\Desktop\';
            app.NombreGIFdesalidaLabel = uilabel(app.HacerGIFUIFigure);
            app.NombreGIFdesalidaLabel.Text = 'NombreGIF de salida:';
            app.edtNombre = uieditfield(app.HacerGIFUIFigure, 'text');
            app.edtNombre.Value = 'Nombre.gif';
            app.btnHacerGif = uibutton(app.HacerGIFUIFigure, 'push');
            app.btnHacerGif.ButtonPushedFcn = createCallbackFcn(app, @btnHacerGifButtonPushed, true);
            app.btnHacerGif.Text = 'Hacer GIF';
            app.LogLabel = uilabel(app.HacerGIFUIFigure);
            app.LogLabel.Text = 'Log:';
            app.edtLog = uieditfield(app.HacerGIFUIFigure, 'text');
            app.edtLog.Editable = 'off';
            app.CarpetaSalidaLabel = uilabel(app.HacerGIFUIFigure);
            app.CarpetaSalidaLabel.Text = 'Carpeta Salida:';
            app.edtCarpeta2 = uieditfield(app.HacerGIFUIFigure, 'text');
            app.edtCarpeta2.Value = 'C:\Users\Danki\Desktop\';
            app.btnAbrir1 = uibutton(app.HacerGIFUIFigure, 'push');
            app.btnAbrir1.ButtonPushedFcn = createCallbackFcn(app, @btnAbrir1ButtonPushed, true);
            app.btnAbrir1.Text = 'Abrir';
            app.btnAbrir2 = uibutton(app.HacerGIFUIFigure, 'push');
            app.btnAbrir2.ButtonPushedFcn = createCallbackFcn(app, @btnAbrir2ButtonPushed, true);
            app.btnAbrir2.Text = 'Abrir';
            app.OpcionesPanel = uipanel(app.HacerGIFUIFigure);
            app.OpcionesPanel.Title = 'Opciones';
            app.ckbGirar = uicheckbox(app.OpcionesPanel);
            app.ckbGirar.Text = 'Girar 180º';
            app.ckbRepetir = uicheckbox(app.OpcionesPanel);
            app.ckbRepetir.Text = 'Boomerang';
            app.OrdenDropDownLabel = uilabel(app.OpcionesPanel);
            app.OrdenDropDownLabel.Text = 'Orden:';
            app.ddOrden = uidropdown(app.OpcionesPanel);
            app.ddOrden.Items = {'Ascendente', 'Descendente'};
            app.ddOrden.Value = 'Ascendente';
            app.ParmetrosPanel = uipanel(app.HacerGIFUIFigure);
            app.ParmetrosPanel.Title = 'Parámetros';
            app.DesdeEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.DesdeEditFieldLabel.Text = 'Desde:';
            app.edtDesde = uieditfield(app.ParmetrosPanel, 'numeric');
            app.HastaEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.HastaEditFieldLabel.Text = 'Hasta:';
            app.edtHasta = uieditfield(app.ParmetrosPanel, 'numeric');
            app.dtsLabel = uilabel(app.ParmetrosPanel);
            app.dtsLabel.Text = 'dt [s]:';
            app.edtDt = uieditfield(app.ParmetrosPanel, 'numeric');
            app.edtDt.Value = 0.5;
            app.EscalaEditFieldLabel = uilabel(app.ParmetrosPanel);
            app.EscalaEditFieldLabel.Text = 'Escala:';
            app.edtEscala = uieditfield(app.ParmetrosPanel, 'numeric');
            app.edtEscala.Value = 1;
            % Show the figure after all components are created
            app.HacerGIFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIFcarpeta(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HacerGIFUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HacerGIFUIFigure)
        end
    end
end